classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoiseApp                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NoiseAddition_Tab              matlab.ui.container.Tab
        NoiseAddition_Command_Panel    matlab.ui.container.Panel
        NoiseAddition_General_Panel    matlab.ui.container.Panel
        NoiseAddition_Browse_Button    matlab.ui.control.Button
        NoiseAddition_Browse_Label     matlab.ui.control.Label
        NoiseAddition_Add_Panel        matlab.ui.container.Panel
        NoiseamountLabel               matlab.ui.control.Label
        NoiseAddition_Amount_Slider    matlab.ui.control.Slider
        NoiseadditionmethodLabel       matlab.ui.control.Label
        NoiseAddition_AdditionMethod_DropDown  matlab.ui.control.DropDown
        NoiseAddition_Generate_Button  matlab.ui.control.Button
        NoiseAddition_Save_Button      matlab.ui.control.Button
        NoiseAddition_Estimate_Panel   matlab.ui.container.Panel
        NoiseAddition_Estimate_Button  matlab.ui.control.Button
        NoiseAddition_EstimationResults_Table  matlab.ui.control.Table
        NoiseAddition_EstimationResults_Label  matlab.ui.control.Label
        NoiseAddition_EstimationMethod_DropDown  matlab.ui.control.DropDown
        NoiseAddition_EstimationMethod_DropDown_Label  matlab.ui.control.Label
        NoiseAddition_GeneratedImage_Image  matlab.ui.control.Image
        NoiseAddition_GeneratedImage_Label  matlab.ui.control.Label
        NoiseAddition_OriginalImage_Image  matlab.ui.control.Image
        NoiseAddition_OriginalImage_Label  matlab.ui.control.Label
        NoiseRemoval_Tab               matlab.ui.container.Tab
        NoiseRemoval_Command_Panel     matlab.ui.container.Panel
        NoiseRemoval_General_Panel     matlab.ui.container.Panel
        NoiseRemoval_Browse_Button     matlab.ui.control.Button
        NoiseRemoval_Browse_Label      matlab.ui.control.Label
        NoiseRemoval_Remove_Panel      matlab.ui.container.Panel
        PatchsizeLabel                 matlab.ui.control.Label
        NoiseRemoval_PatchSize_Slider  matlab.ui.control.Slider
        NoiseremovalmethodLabel        matlab.ui.control.Label
        NoiseRemoval_RemovalMethod_DropDown  matlab.ui.control.DropDown
        NoiseRemoval_Generate_Button   matlab.ui.control.Button
        NoiseRemoval_Save_Button       matlab.ui.control.Button
        NoiseRemoval_Estimate_Panel    matlab.ui.container.Panel
        NoiseestimationmethodLabel_2   matlab.ui.control.Label
        NoiseRemoval_EstimationMethod_DropDown  matlab.ui.control.DropDown
        NoiseRemoval_Estimate_Button   matlab.ui.control.Button
        NoiseRemoval_EstimationResults_Table  matlab.ui.control.Table
        NoiseRemoval_EstimationResults_Label  matlab.ui.control.Label
        NoiseRemoval_GeneratedImage_Image  matlab.ui.control.Image
        NoiseRemoval_GeneratedImage_Label  matlab.ui.control.Label
        NoiseRemoval_OriginalImage_Image  matlab.ui.control.Image
        NoiseRemoval_OriginalImage_Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        NoiseAddition_OriginalImage;
        NoiseAddition_GeneratedImage;
        NoiseRemoval_OriginalImage;
        NoiseRemoval_GeneratedImage;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%add
            app.NoiseAddition_Generate_Button.Enable = false;
            app.NoiseAddition_Save_Button.Enable = false;
            app.NoiseAddition_Estimate_Button.Enable = false;
            
            app.NoiseAddition_OriginalImage_Image.Visible = false;
            app.NoiseAddition_GeneratedImage_Image.Visible = false;
    
            app.NoiseAddition_EstimationResults_Table.Data =  {'N/A', 'N/A'; 'N/A', 'N/A';'N/A', 'N/A';'N/A', 'N/A'};
            
            %%remove
            app.NoiseRemoval_Generate_Button.Enable = false;
            app.NoiseRemoval_Save_Button.Enable = false;
            app.NoiseRemoval_Estimate_Button.Enable = false;
            
            app.NoiseRemoval_OriginalImage_Image.Visible = false;
            app.NoiseRemoval_GeneratedImage_Image.Visible = false;
    
            app.NoiseRemoval_EstimationResults_Table.Data =  {'N/A', 'N/A'; 'N/A', 'N/A';'N/A', 'N/A';'N/A', 'N/A'};
        end

        % Button pushed function: NoiseAddition_Browse_Button
        function NoiseAddition_Browse_ButtonPushed(app, event)
            [file,path] = uigetfile('*.png');
            figure(app.NoiseApp);
            if not(isequal(file,0))
                app.NoiseAddition_Generate_Button.Enable = false;
                app.NoiseAddition_Save_Button.Enable = false;
                app.NoiseAddition_Estimate_Button.Enable = false;
                dialog = uiprogressdlg(app.NoiseApp,'Title','Loading image','Indeterminate','on');
                
                app.NoiseAddition_OriginalImage = im2double(imread(fullfile(path,file)));
                app.NoiseAddition_GeneratedImage = [];
                app.NoiseAddition_EstimationResults_Table.Data =  {'N/A', 'N/A'; 'N/A', 'N/A';'N/A', 'N/A';'N/A', 'N/A'};
                
                app.NoiseAddition_Generate_Button.Enable = true;
                app.NoiseAddition_Estimate_Button.Enable = true;
                
                app.NoiseAddition_OriginalImage_Image.ImageSource = app.NoiseAddition_OriginalImage;
                app.NoiseAddition_OriginalImage_Image.Visible = true;
                app.NoiseAddition_GeneratedImage_Image.Visible = false;
                close(dialog);
            end
        end

        % Button pushed function: NoiseAddition_Generate_Button
        function NoiseAddition_Generate_ButtonPushed(app, event)
            app.NoiseAddition_Save_Button.Enable = false;
            dialog = uiprogressdlg(app.NoiseApp,'Title','Generating image','Indeterminate','on');
            app.NoiseAddition_EstimationResults_Table.Data(:,2) =  {'N/A', 'N/A', 'N/A', 'N/A'};

            switch app.NoiseAddition_AdditionMethod_DropDown.Value
                case 'Gauss'
                    [app.NoiseAddition_GeneratedImage] = NoiseLevelAddition_Gauss(app.NoiseAddition_OriginalImage, app.NoiseAddition_Amount_Slider.Value);
                case 'Periodic'
                    [app.NoiseAddition_GeneratedImage] = NoiseLevelAddition_PeriodicNoise(app.NoiseAddition_OriginalImage, app.NoiseAddition_Amount_Slider.Value);
                case 'Salt & Pepper'
                    [app.NoiseAddition_GeneratedImage] = NoiseLevelAddition_SaltAndPepper(app.NoiseAddition_OriginalImage, app.NoiseAddition_Amount_Slider.Value);
                case 'Speckle'
                    [app.NoiseAddition_GeneratedImage] = NoiseLevelAddition_Speckle(app.NoiseAddition_OriginalImage, app.NoiseAddition_Amount_Slider.Value); 
            end
            
            app.NoiseAddition_GeneratedImage_Image.ImageSource = app.NoiseAddition_GeneratedImage;
            app.NoiseAddition_GeneratedImage_Image.Visible = true;
            app.NoiseAddition_Save_Button.Enable = true;
            close(dialog);
        end

        % Button pushed function: NoiseAddition_Save_Button
        function NoiseAddition_Save_ButtonPushed(app, event)
            [file,path] = uiputfile("*.png");
            figure(app.NoiseApp);
            if not(isequal(file,0))
                dialog = uiprogressdlg(app.NoiseApp,'Title','Saving image','Indeterminate','on');
                imwrite(app.NoiseAddition_GeneratedImage, fullfile(path,file));
                close(dialog);
            end
        end

        % Button pushed function: NoiseAddition_Estimate_Button
        function NoiseAddition_Estimate_ButtonPushed(app, event)
            dialog = uiprogressdlg(app.NoiseApp,'Title','Estimating noise levels','Indeterminate','on');
            
            if(not(isempty(app.NoiseAddition_OriginalImage)))
                switch app.NoiseAddition_EstimationMethod_DropDown.Value
                    case 'Frequency outliers'
                        [nLevels] = NoiseLevelEstimation_FrequencyOutlinersMethod(app.NoiseAddition_OriginalImage);
                    case 'Local variance'
                        [nLevels] = NoiseLevelEstimation_NoiseLocalVarianceMeanMethod(app.NoiseAddition_OriginalImage, 3);
                    case 'Weak textures'
                        [nLevels] = NoiseLevelEstimation_WeakTexturesMethod(app.NoiseAddition_OriginalImage, 3); 
                end
                db = 3-size(nLevels, 2);
                noiseLevels = [num2cell(nLevels), repmat({'N/A'}, 1, db), mean(nLevels)];
                app.NoiseAddition_EstimationResults_Table.Data(:,1) =  noiseLevels;
            else
                app.NoiseAddition_EstimationResults_Table.Data(:,2) =  { 'N/A', 'N/A', 'N/A', 'N/A'};
            end
            if(not(isempty(app.NoiseAddition_GeneratedImage)))
                switch app.NoiseAddition_EstimationMethod_DropDown.Value
                    case 'Frequency outliers'
                        [nLevels] = NoiseLevelEstimation_FrequencyOutlinersMethod(app.NoiseAddition_GeneratedImage);
                    case 'Local variance'
                        [nLevels] = NoiseLevelEstimation_NoiseLocalVarianceMeanMethod(app.NoiseAddition_GeneratedImage, 3);
                    case 'Weak textures'
                        [nLevels] = NoiseLevelEstimation_WeakTexturesMethod(app.NoiseAddition_GeneratedImage, 3); 
                end
                db = 3-size(nLevels, 2);
                noiseLevels = [num2cell(nLevels), repmat({'N/A'}, 1, db), mean(nLevels)];
                app.NoiseAddition_EstimationResults_Table.Data(:,2) =  noiseLevels;
            else
                app.NoiseAddition_EstimationResults_Table.Data(:,2) =  { 'N/A', 'N/A', 'N/A', 'N/A'};
            end
            
            close(dialog);
        end

        % Value changed function: 
        % NoiseRemoval_RemovalMethod_DropDown
        function NoiseRemoval_RemovalMethod_DropDownValueChanged(app, event)
            value = app.NoiseRemoval_RemovalMethod_DropDown.Value;
            if (value == "Frequency outliners")
                app.NoiseRemoval_PatchSize_Slider.Enable = false;
            else
                app.NoiseRemoval_PatchSize_Slider.Enable = true;
            end
        end

        % Value changed function: NoiseRemoval_PatchSize_Slider
        function NoiseRemoval_PatchSize_SliderValueChanged(app, event)
            value = app.NoiseRemoval_PatchSize_Slider.Value;
            app.NoiseRemoval_PatchSize_Slider.Value = 2*floor(value/2)+1;
        end

        % Button pushed function: NoiseRemoval_Browse_Button
        function NoiseRemoval_Browse_ButtonPushed(app, event)
            [file,path] = uigetfile('*.png');
            figure(app.NoiseApp);
            if not(isequal(file,0))
                app.NoiseRemoval_Generate_Button.Enable = false;
                app.NoiseRemoval_Save_Button.Enable = false;
                app.NoiseRemoval_Estimate_Button.Enable = false;
                dialog = uiprogressdlg(app.NoiseApp,'Title','Loading image','Indeterminate','on');
                
                app.NoiseRemoval_OriginalImage = im2double(imread(fullfile(path,file)));
                app.NoiseRemoval_GeneratedImage = [];
                app.NoiseRemoval_EstimationResults_Table.Data =  {'N/A', 'N/A'; 'N/A', 'N/A';'N/A', 'N/A';'N/A', 'N/A'};
                
                app.NoiseRemoval_Generate_Button.Enable = true;
                app.NoiseRemoval_Estimate_Button.Enable = true;
                
                app.NoiseRemoval_OriginalImage_Image.ImageSource = app.NoiseRemoval_OriginalImage;
                app.NoiseRemoval_OriginalImage_Image.Visible = true;
                app.NoiseRemoval_GeneratedImage_Image.Visible = false;
                close(dialog);
            end
        end

        % Button pushed function: NoiseRemoval_Generate_Button
        function NoiseRemoval_Generate_ButtonPushed(app, event)
            app.NoiseRemoval_Save_Button.Enable = false;
            dialog = uiprogressdlg(app.NoiseApp,'Title','Generating image','Indeterminate','on');
            app.NoiseRemoval_EstimationResults_Table.Data(:,2) =  {'N/A', 'N/A', 'N/A', 'N/A'};

            switch app.NoiseRemoval_RemovalMethod_DropDown.Value
                case 'Frequency outliners'
                    [app.NoiseRemoval_GeneratedImage] = NoiseLevelRemoval_FrequencyOutlinersMethod(app.NoiseRemoval_OriginalImage);
                case 'Mean'
                    [app.NoiseRemoval_GeneratedImage] = NoiseLevelRemoval_MeanMethod(app.NoiseRemoval_OriginalImage, app.NoiseRemoval_PatchSize_Slider.Value);
                case 'Median'
                    [app.NoiseRemoval_GeneratedImage] = NoiseLevelRemoval_MedianMethod(app.NoiseRemoval_OriginalImage, app.NoiseRemoval_PatchSize_Slider.Value);
                case 'Wiener'
                    [app.NoiseRemoval_GeneratedImage] = NoiseLevelRemoval_WienerMethod(app.NoiseRemoval_OriginalImage, app.NoiseRemoval_PatchSize_Slider.Value); 
            end
            
            app.NoiseRemoval_GeneratedImage_Image.ImageSource = app.NoiseRemoval_GeneratedImage;
            app.NoiseRemoval_GeneratedImage_Image.Visible = true;
            app.NoiseRemoval_Save_Button.Enable = true;
            close(dialog);
        end

        % Button pushed function: NoiseRemoval_Save_Button
        function NoiseRemoval_Save_ButtonPushed(app, event)
            [file,path] = uiputfile("*.png");
            figure(app.NoiseApp);
            if not(isequal(file,0))
                dialog = uiprogressdlg(app.NoiseApp,'Title','Saving image','Indeterminate','on');
                imwrite(app.NoiseRemoval_GeneratedImage, fullfile(path,file));
                close(dialog);
            end
        end

        % Button pushed function: NoiseRemoval_Estimate_Button
        function NoiseRemoval_Estimate_ButtonPushed(app, event)
            dialog = uiprogressdlg(app.NoiseApp,'Title','Estimating noise levels','Indeterminate','on');
            
            if(not(isempty(app.NoiseRemoval_OriginalImage)))
                switch app.NoiseRemoval_EstimationMethod_DropDown.Value
                    case 'Frequency outliers'
                        [nLevels] = NoiseLevelEstimation_FrequencyOutlinersMethod(app.NoiseRemoval_OriginalImage);
                    case 'Local variance'
                        [nLevels] = NoiseLevelEstimation_NoiseLocalVarianceMeanMethod(app.NoiseRemoval_OriginalImage, 3);
                    case 'Weak textures'
                        [nLevels] = NoiseLevelEstimation_WeakTexturesMethod(app.NoiseRemoval_OriginalImage, 3); 
                end
                db = 3-size(nLevels, 2);
                noiseLevels = [num2cell(nLevels), repmat({'N/A'}, 1, db), mean(nLevels)];
                app.NoiseRemoval_EstimationResults_Table.Data(:,1) =  noiseLevels;
            else
                app.NoiseRemoval_EstimationResults_Table.Data(:,2) =  { 'N/A', 'N/A', 'N/A', 'N/A'};
            end
            if(not(isempty(app.NoiseRemoval_GeneratedImage)))
                switch app.NoiseRemoval_EstimationMethod_DropDown.Value
                    case 'Frequency outliers'
                        [nLevels] = NoiseLevelEstimation_FrequencyOutlinersMethod(app.NoiseRemoval_GeneratedImage);
                    case 'Local variance'
                        [nLevels] = NoiseLevelEstimation_NoiseLocalVarianceMeanMethod(app.NoiseRemoval_GeneratedImage, 3);
                    case 'Weak textures'
                        [nLevels] = NoiseLevelEstimation_WeakTexturesMethod(app.NoiseRemoval_GeneratedImage, 3); 
                end
                db = 3-size(nLevels, 2);
                noiseLevels = [num2cell(nLevels), repmat({'N/A'}, 1, db), mean(nLevels)];
                app.NoiseRemoval_EstimationResults_Table.Data(:,2) =  noiseLevels;
            else
                app.NoiseRemoval_EstimationResults_Table.Data(:,2) =  { 'N/A', 'N/A', 'N/A', 'N/A'};
            end
            
            close(dialog);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NoiseApp = uifigure('Visible', 'off');
            app.NoiseApp.Name = 'NoiseApp';
            app.TabGroup = uitabgroup(app.NoiseApp);
            app.NoiseAddition_Tab = uitab(app.TabGroup);
            app.NoiseAddition_Tab.Title = 'Noise addition';
            app.NoiseAddition_Command_Panel = uipanel(app.NoiseAddition_Tab);
            app.NoiseAddition_General_Panel = uipanel(app.NoiseAddition_Command_Panel);
            app.NoiseAddition_Browse_Button = uibutton(app.NoiseAddition_General_Panel, 'push');
            app.NoiseAddition_Browse_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseAddition_Browse_ButtonPushed, true);
            app.NoiseAddition_Browse_Button.Text = 'Browse';
            app.NoiseAddition_Browse_Label = uilabel(app.NoiseAddition_General_Panel);
            app.NoiseAddition_Browse_Label.Text = 'Open image';
            app.NoiseAddition_Add_Panel = uipanel(app.NoiseAddition_Command_Panel);
            app.NoiseamountLabel = uilabel(app.NoiseAddition_Add_Panel);
            app.NoiseamountLabel.Text = 'Noise amount';
            app.NoiseAddition_Amount_Slider = uislider(app.NoiseAddition_Add_Panel);
            app.NoiseadditionmethodLabel = uilabel(app.NoiseAddition_Add_Panel);
            app.NoiseadditionmethodLabel.Text = 'Noise addition method';
            app.NoiseAddition_AdditionMethod_DropDown = uidropdown(app.NoiseAddition_Add_Panel);
            app.NoiseAddition_AdditionMethod_DropDown.Items = {'Gauss', 'Periodic', 'Salt & Pepper', 'Speckle'};
            app.NoiseAddition_AdditionMethod_DropDown.Value = 'Gauss';
            app.NoiseAddition_Generate_Button = uibutton(app.NoiseAddition_Add_Panel, 'push');
            app.NoiseAddition_Generate_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseAddition_Generate_ButtonPushed, true);
            app.NoiseAddition_Generate_Button.Text = 'Generate image';
            app.NoiseAddition_Save_Button = uibutton(app.NoiseAddition_Add_Panel, 'push');
            app.NoiseAddition_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseAddition_Save_ButtonPushed, true);
            app.NoiseAddition_Save_Button.Text = 'Save';
            app.NoiseAddition_Estimate_Panel = uipanel(app.NoiseAddition_Command_Panel);
            app.NoiseAddition_Estimate_Button = uibutton(app.NoiseAddition_Estimate_Panel, 'push');
            app.NoiseAddition_Estimate_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseAddition_Estimate_ButtonPushed, true);
            app.NoiseAddition_Estimate_Button.Text = 'Estimate noise levels';
            app.NoiseAddition_EstimationResults_Table = uitable(app.NoiseAddition_Estimate_Panel);
            app.NoiseAddition_EstimationResults_Table.ColumnName = {'Original'; 'Generated'};
            app.NoiseAddition_EstimationResults_Table.RowName = {'Red'; 'Green'; 'Blue'; 'Avg.'};
            app.NoiseAddition_EstimationResults_Label = uilabel(app.NoiseAddition_Estimate_Panel);
            app.NoiseAddition_EstimationResults_Label.Text = 'Estimated noise levels';
            app.NoiseAddition_EstimationMethod_DropDown = uidropdown(app.NoiseAddition_Estimate_Panel);
            app.NoiseAddition_EstimationMethod_DropDown.Items = {'Frequency outliers', 'Local variance', 'Weak textures'};
            app.NoiseAddition_EstimationMethod_DropDown.Value = 'Frequency outliers';
            app.NoiseAddition_EstimationMethod_DropDown_Label = uilabel(app.NoiseAddition_Estimate_Panel);
            app.NoiseAddition_EstimationMethod_DropDown_Label.Text = 'Noise estimation method';
            app.NoiseAddition_GeneratedImage_Image = uiimage(app.NoiseAddition_Tab);
            app.NoiseAddition_GeneratedImage_Label = uilabel(app.NoiseAddition_Tab);
            app.NoiseAddition_GeneratedImage_Label.Text = 'Generated image';
            app.NoiseAddition_OriginalImage_Image = uiimage(app.NoiseAddition_Tab);
            app.NoiseAddition_OriginalImage_Label = uilabel(app.NoiseAddition_Tab);
            app.NoiseAddition_OriginalImage_Label.Text = 'Original image';
            app.NoiseRemoval_Tab = uitab(app.TabGroup);
            app.NoiseRemoval_Tab.Title = 'Noise removal';
            app.NoiseRemoval_Command_Panel = uipanel(app.NoiseRemoval_Tab);
            app.NoiseRemoval_General_Panel = uipanel(app.NoiseRemoval_Command_Panel);
            app.NoiseRemoval_Browse_Button = uibutton(app.NoiseRemoval_General_Panel, 'push');
            app.NoiseRemoval_Browse_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseRemoval_Browse_ButtonPushed, true);
            app.NoiseRemoval_Browse_Button.Text = 'Browse';
            app.NoiseRemoval_Browse_Label = uilabel(app.NoiseRemoval_General_Panel);
            app.NoiseRemoval_Browse_Label.Text = 'Open image';
            app.NoiseRemoval_Remove_Panel = uipanel(app.NoiseRemoval_Command_Panel);
            app.PatchsizeLabel = uilabel(app.NoiseRemoval_Remove_Panel);
            app.PatchsizeLabel.Text = 'Patch size';
            app.NoiseRemoval_PatchSize_Slider = uislider(app.NoiseRemoval_Remove_Panel);
            app.NoiseRemoval_PatchSize_Slider.Limits = [3 15];
            app.NoiseRemoval_PatchSize_Slider.ValueChangedFcn = createCallbackFcn(app, @NoiseRemoval_PatchSize_SliderValueChanged, true);
            app.NoiseRemoval_PatchSize_Slider.Enable = 'off';
            app.NoiseRemoval_PatchSize_Slider.Value = 3;
            app.NoiseremovalmethodLabel = uilabel(app.NoiseRemoval_Remove_Panel);
            app.NoiseremovalmethodLabel.Text = 'Noise removal method';
            app.NoiseRemoval_RemovalMethod_DropDown = uidropdown(app.NoiseRemoval_Remove_Panel);
            app.NoiseRemoval_RemovalMethod_DropDown.Items = {'Frequency outliners', 'Mean', 'Median', 'Wiener'};
            app.NoiseRemoval_RemovalMethod_DropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseRemoval_RemovalMethod_DropDownValueChanged, true);
            app.NoiseRemoval_RemovalMethod_DropDown.Value = 'Frequency outliners';
            app.NoiseRemoval_Generate_Button = uibutton(app.NoiseRemoval_Remove_Panel, 'push');
            app.NoiseRemoval_Generate_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseRemoval_Generate_ButtonPushed, true);
            app.NoiseRemoval_Generate_Button.Text = 'Generate image';
            app.NoiseRemoval_Save_Button = uibutton(app.NoiseRemoval_Remove_Panel, 'push');
            app.NoiseRemoval_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseRemoval_Save_ButtonPushed, true);
            app.NoiseRemoval_Save_Button.Text = 'Save';
            app.NoiseRemoval_Estimate_Panel = uipanel(app.NoiseRemoval_Command_Panel);
            app.NoiseestimationmethodLabel_2 = uilabel(app.NoiseRemoval_Estimate_Panel);
            app.NoiseestimationmethodLabel_2.Text = 'Noise estimation method';
            app.NoiseRemoval_EstimationMethod_DropDown = uidropdown(app.NoiseRemoval_Estimate_Panel);
            app.NoiseRemoval_EstimationMethod_DropDown.Items = {'Frequency outliers', 'Local variance', 'Weak textures'};
            app.NoiseRemoval_EstimationMethod_DropDown.Value = 'Frequency outliers';
            app.NoiseRemoval_Estimate_Button = uibutton(app.NoiseRemoval_Estimate_Panel, 'push');
            app.NoiseRemoval_Estimate_Button.ButtonPushedFcn = createCallbackFcn(app, @NoiseRemoval_Estimate_ButtonPushed, true);
            app.NoiseRemoval_Estimate_Button.Text = 'Estimate noise levels';
            app.NoiseRemoval_EstimationResults_Table = uitable(app.NoiseRemoval_Estimate_Panel);
            app.NoiseRemoval_EstimationResults_Table.ColumnName = {'Original'; 'Generated'};
            app.NoiseRemoval_EstimationResults_Table.RowName = {'Red'; 'Green'; 'Blue'; 'Avg.'};
            app.NoiseRemoval_EstimationResults_Label = uilabel(app.NoiseRemoval_Estimate_Panel);
            app.NoiseRemoval_EstimationResults_Label.Text = 'Estimated noise levels';
            app.NoiseRemoval_GeneratedImage_Image = uiimage(app.NoiseRemoval_Tab);
            app.NoiseRemoval_GeneratedImage_Label = uilabel(app.NoiseRemoval_Tab);
            app.NoiseRemoval_GeneratedImage_Label.Text = 'Generated image';
            app.NoiseRemoval_OriginalImage_Image = uiimage(app.NoiseRemoval_Tab);
            app.NoiseRemoval_OriginalImage_Label = uilabel(app.NoiseRemoval_Tab);
            app.NoiseRemoval_OriginalImage_Label.Text = 'Original image';
            % Show the figure after all components are created
            app.NoiseApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoiseApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoiseApp)
        end
    end
end